classdef XPCI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ExperimentalSetupPanel          matlab.ui.container.Panel
        DistanceYAGtoLensEditField      matlab.ui.control.NumericEditField
        DistanceYAGtoLensmLabel         matlab.ui.control.Label
        DistanceSampletoYAGEditField    matlab.ui.control.NumericEditField
        DistanceSampletoYAGmEditFieldLabel  matlab.ui.control.Label
        DistanceWindowtoSampleEditField  matlab.ui.control.NumericEditField
        DistanceWindowtoSamplemEditFieldLabel  matlab.ui.control.Label
        DistanceIPtoWindowEditField     matlab.ui.control.NumericEditField
        DistanceIPtowindowmLabel        matlab.ui.control.Label
        XraybeamparamtersPanel          matlab.ui.container.Panel
        XrayEnergyeVDropDown            matlab.ui.control.DropDown
        XrayEnergyeVDropDownLabel       matlab.ui.control.Label
        NumberofPulsesEditField         matlab.ui.control.NumericEditField
        NumberofPulsesEditFieldLabel    matlab.ui.control.Label
        FluxperPulseEditField           matlab.ui.control.NumericEditField
        FluxperPulsephotonsEditFieldLabel  matlab.ui.control.Label
        XRayFWOpeningAngleEditField     matlab.ui.control.NumericEditField
        XRayFWOpeningAnglemradLabel     matlab.ui.control.Label
        EquipmentsetupPanel             matlab.ui.container.Panel
        YAGTransmissionEditField        matlab.ui.control.NumericEditField
        TransmissionEditFieldLabel      matlab.ui.control.Label
        FluorescenceEmissionEditField   matlab.ui.control.NumericEditField
        FluorescenceEmissionphotonseVEditFieldLabel  matlab.ui.control.Label
        YAGDiameterEditField            matlab.ui.control.NumericEditField
        DiametermEditFieldLabel         matlab.ui.control.Label
        LensFocalDistanceEditField      matlab.ui.control.NumericEditField
        LensFocalDistancemEditFieldLabel  matlab.ui.control.Label
        QuantumEfficiencyEditField      matlab.ui.control.NumericEditField
        QuantumEfficiencyEditField_2Label  matlab.ui.control.Label
        LensfNumberEditField            matlab.ui.control.NumericEditField
        LensfNumberEditFieldLabel       matlab.ui.control.Label
        NumberofBitsEditField           matlab.ui.control.NumericEditField
        NumberofBitsEditFieldLabel      matlab.ui.control.Label
        WellDepthEditField              matlab.ui.control.NumericEditField
        WellDepthEditFieldLabel         matlab.ui.control.Label
        PixelsVerticalEditField         matlab.ui.control.NumericEditField
        PixelsverticalEditFieldLabel    matlab.ui.control.Label
        ScintillatorDropDown            matlab.ui.control.DropDown
        ScintillatorDropDownLabel       matlab.ui.control.Label
        SensorDropDown                  matlab.ui.control.DropDown
        SensorDropDownLabel             matlab.ui.control.Label
        CameraDropDown                  matlab.ui.control.DropDown
        CameraDropDownLabel             matlab.ui.control.Label
        PixelSizeEditField              matlab.ui.control.NumericEditField
        PixelSizemEditFieldLabel        matlab.ui.control.Label
        PixelsHorizontalEditField       matlab.ui.control.NumericEditField
        PixelshorizontalEditFieldLabel  matlab.ui.control.Label
        PlotsPanel                      matlab.ui.container.Panel
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        XPCIForwardPropagationSimulatorLabel  matlab.ui.control.Label
        CalculateandPlotButton          matlab.ui.control.Button
        OutputsPanel                    matlab.ui.container.Panel
        EffectivePixelSizeVerticalmEditField  matlab.ui.control.NumericEditField
        EffectivePixelSizeVerticalmLabel  matlab.ui.control.Label
        EffectivePixelSizeHorizontalmEditField  matlab.ui.control.NumericEditField
        EffectivePixelSizeHorizontalmLabel  matlab.ui.control.Label
        BeamDiameteratSamplemEditField_2  matlab.ui.control.NumericEditField
        BeamDiameteratSamplemEditField_2Label  matlab.ui.control.Label
        BeamDiameteratSamplemEditField  matlab.ui.control.NumericEditField
        BeamDiameteratSamplemEditFieldLabel  matlab.ui.control.Label
        BeamDiameteratYAGmEditField     matlab.ui.control.NumericEditField
        BeamDiameteratYAGmEditFieldLabel  matlab.ui.control.Label
        CameraMagnificationEditField    matlab.ui.control.NumericEditField
        CameraMagnificationEditFieldLabel  matlab.ui.control.Label
        FieldofViewVerticalmEditField   matlab.ui.control.NumericEditField
        FieldofViewVerticalmEditFieldLabel  matlab.ui.control.Label
        FieldofViewHorizontalmEditField  matlab.ui.control.NumericEditField
        FieldofViewHorizontalmEditFieldLabel  matlab.ui.control.Label
        SampleParametersPanel           matlab.ui.container.Panel
        SampleMaterialDropDown          matlab.ui.control.DropDown
        SampleMaterialDropDownLabel     matlab.ui.control.Label
        SampleThicknessEditField        matlab.ui.control.NumericEditField
        SampleThicknessmEditFieldLabel  matlab.ui.control.Label
        SampleDiameterEditField         matlab.ui.control.NumericEditField
        SampleDiametermEditFieldLabel   matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: CalculateandPlotButton
        function CalculateandPlotButtonPushed(app, event)

                      
            % Read user input

            YAG_diameter = app.YAGDiameterEditField.Value;
            YAG_fluorescence_emission = app.FluorescenceEmissionEditField.Value;

            flux_IP_per_pulse = app.FluxperPulseEditField.Value; 
            number_pulses = app.NumberofPulsesEditField.Value;
            opening_angle = app.XRayFWOpeningAngleEditField.Value;

            sample_diameter = app.SampleDiameterEditField.Value;
            sample_thickness = app.SampleThicknessEditField.Value;

            distance_IP_window = app.DistanceIPtoWindowEditField.Value;
            distance_window_sample = app.DistanceWindowtoSampleEditField.Value;
            distance_sample_YAG = app.DistanceSampletoYAGEditField.Value;
            distance_YAG_lens = app.DistanceYAGtoLensEditField.Value;
            sample_type = app.SampleMaterialDropDown.Value;


            val = app.XrayEnergyeVDropDown.Value;
            if isnumeric(val) && isscalar(val)
                xray_photon_energy = val;
            elseif isstring(val) || ischar(val)
            % try to parse numeric text like '9300' or '9.3 keV'
                xray_photon_energy = str2double(regexp(char(val), '[-+]?\d*\.?\d+(?:[eE][-+]?\d+)?', 'match', 'once'));
            end


            [effective_pixel_size_horizontal,effective_pixel_size_vertical, FOV_horizontal, FOV_vertical, lens_magnification, beam_radius_YAG, beam_radius_sample, sensor_intensities, sensor_intensities_with_sample] = XPCI_propagation(sample_type, xray_photon_energy, YAG_diameter, YAG_fluorescence_emission, flux_IP_per_pulse, number_pulses, opening_angle, sample_diameter, sample_thickness, distance_IP_window, distance_window_sample, distance_sample_YAG, distance_YAG_lens);

            % Display transmission value
            
            app.FieldofViewHorizontalmEditField.Value = FOV_horizontal;
            app.FieldofViewVerticalmEditField.Value = FOV_vertical;
            app.CameraMagnificationEditField.Value = lens_magnification;
            app.BeamDiameteratYAGmEditField.Value = beam_radius_YAG*2;
            app.BeamDiameteratSamplemEditField.Value = beam_radius_sample*2;
            app.EffectivePixelSizeHorizontalmEditField.Value = effective_pixel_size_horizontal;
            app.EffectivePixelSizeVerticalmEditField.Value = effective_pixel_size_vertical;

            % assume sensor_intensities is MxN (rows,cols)
            [M, N] = size(sensor_intensities');

            % First axes (transpose if you really want rotated display)
            imagesc(app.UIAxes, sensor_intensities');          % display
            app.UIAxes.YDir = 'normal';
            app.UIAxes.DataAspectRatio = [1 1 1];               % 1:1 aspect (one data unit == one screen unit)
            app.UIAxes.XLim = [0.5, N + 0.5];                   % align pixel centers
            app.UIAxes.YLim = [0.5, M + 0.5];
            c1 = colorbar(app.UIAxes);            % returns ColorBar object for app.UIAxes
            c1.Label.String = 'Intensity (counts)'; % set label text
            c1.Label.FontSize = 10;               % optional
            c1.Label.Interpreter = 'none';        % or 'tex'/'latex'

            % Second axes
            imagesc(app.UIAxes2, sensor_intensities_with_sample');
            app.UIAxes2.YDir = 'normal';
            app.UIAxes2.DataAspectRatio = [1 1 1];
            app.UIAxes2.XLim = [0.5, N + 0.5];
            app.UIAxes2.YLim = [0.5, M + 0.5];
            c2 = colorbar(app.UIAxes2);            % returns ColorBar object for app.UIAxes
            c2.Label.String = 'Intensity (counts)'; % set label text
            c2.Label.FontSize = 10;               % optional
            c2.Label.Interpreter = 'none';        % or 'tex'/'latex'

            % Optional: hide ticks/labels and fix colormap/clims for consistent coloring
           % axis(app.UIAxes, 'off');
           % axis(app.UIAxes2, 'off');
            colormap(app.UIAxes, parula);
            colormap(app.UIAxes2, parula);
            cmin = min([sensor_intensities(:); sensor_intensities_with_sample(:)]);
            cmax = max([sensor_intensities(:); sensor_intensities_with_sample(:)]);
            caxis(app.UIAxes, [cmin cmax]);
            caxis(app.UIAxes2, [cmin cmax]);

            % Ensure the axes use their Position for rendering (needed if you resize programmatically)
            app.UIAxes.ActivePositionProperty  = 'position';
            app.UIAxes2.ActivePositionProperty = 'position';
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SampleParametersPanel = uipanel(app.UIFigure);
            app.SampleParametersPanel.Title = 'Sample Parameters';
            app.SampleDiametermEditFieldLabel = uilabel(app.SampleParametersPanel);
            app.SampleDiametermEditFieldLabel.Text = 'Sample Diameter [m]';
            app.SampleDiameterEditField = uieditfield(app.SampleParametersPanel, 'numeric');
            app.SampleDiameterEditField.Value = 0.01;
            app.SampleThicknessmEditFieldLabel = uilabel(app.SampleParametersPanel);
            app.SampleThicknessmEditFieldLabel.Text = 'Sample Thickness [m]';
            app.SampleThicknessEditField = uieditfield(app.SampleParametersPanel, 'numeric');
            app.SampleThicknessEditField.Value = 0.001;
            app.SampleMaterialDropDownLabel = uilabel(app.SampleParametersPanel);
            app.SampleMaterialDropDownLabel.Text = 'Sample Material';
            app.SampleMaterialDropDown = uidropdown(app.SampleParametersPanel);
            app.SampleMaterialDropDown.Items = {'H2O', 'Tumor Contrast'};
            app.SampleMaterialDropDown.Value = 'H2O';
            app.OutputsPanel = uipanel(app.UIFigure);
            app.OutputsPanel.Title = 'Outputs';
            app.FieldofViewHorizontalmEditFieldLabel = uilabel(app.OutputsPanel);
            app.FieldofViewHorizontalmEditFieldLabel.Text = 'Field of View - Horizontal [m]';
            app.FieldofViewHorizontalmEditField = uieditfield(app.OutputsPanel, 'numeric');
            app.FieldofViewVerticalmEditFieldLabel = uilabel(app.OutputsPanel);
            app.FieldofViewVerticalmEditFieldLabel.Text = 'Field of View - Vertical [m]';
            app.FieldofViewVerticalmEditField = uieditfield(app.OutputsPanel, 'numeric');
            app.CameraMagnificationEditFieldLabel = uilabel(app.OutputsPanel);
            app.CameraMagnificationEditFieldLabel.Text = 'Camera Magnification';
            app.CameraMagnificationEditField = uieditfield(app.OutputsPanel, 'numeric');
            app.BeamDiameteratYAGmEditFieldLabel = uilabel(app.OutputsPanel);
            app.BeamDiameteratYAGmEditFieldLabel.Text = 'Beam Diameter at YAG [m]';
            app.BeamDiameteratYAGmEditField = uieditfield(app.OutputsPanel, 'numeric');
            app.BeamDiameteratSamplemEditFieldLabel = uilabel(app.OutputsPanel);
            app.BeamDiameteratSamplemEditFieldLabel.Text = 'Beam Diameter at Sample [m]';
            app.BeamDiameteratSamplemEditField = uieditfield(app.OutputsPanel, 'numeric');
            app.BeamDiameteratSamplemEditField_2Label = uilabel(app.OutputsPanel);
            app.BeamDiameteratSamplemEditField_2Label.Text = 'Beam Diameter at Sample [m]';
            app.BeamDiameteratSamplemEditField_2 = uieditfield(app.OutputsPanel, 'numeric');
            app.EffectivePixelSizeHorizontalmLabel = uilabel(app.OutputsPanel);
            app.EffectivePixelSizeHorizontalmLabel.Text = 'Effective Pixel Size - Horizontal [m]';
            app.EffectivePixelSizeHorizontalmEditField = uieditfield(app.OutputsPanel, 'numeric');
            app.EffectivePixelSizeVerticalmLabel = uilabel(app.OutputsPanel);
            app.EffectivePixelSizeVerticalmLabel.Text = 'Effective Pixel Size - Vertical [m]';
            app.EffectivePixelSizeVerticalmEditField = uieditfield(app.OutputsPanel, 'numeric');
            app.CalculateandPlotButton = uibutton(app.UIFigure, 'push');
            app.CalculateandPlotButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateandPlotButtonPushed, true);
            app.CalculateandPlotButton.Text = 'Calculate and Plot';
            app.XPCIForwardPropagationSimulatorLabel = uilabel(app.UIFigure);
            app.XPCIForwardPropagationSimulatorLabel.Text = 'XPCI Forward Propagation Simulator';
            app.PlotsPanel = uipanel(app.UIFigure);
            app.PlotsPanel.Title = 'Plots';
            app.UIAxes = uiaxes(app.PlotsPanel);
            title(app.UIAxes, 'Imaging YAG without Sample')
            xlabel(app.UIAxes, 'Pixels')
            ylabel(app.UIAxes, 'Pixels')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 500 1000 1500 2000];
            app.UIAxes.XTickLabel = {'0'; '500'; '1000'; '1500'; '2000'};
            app.UIAxes.YTick = [0 500 1000 1500 2000];
            app.UIAxes.YTickLabel = {'0'; '500'; '1000'; '1500'; '2000'};
            app.UIAxes2 = uiaxes(app.PlotsPanel);
            title(app.UIAxes2, 'Imaging YAG with Sample')
            xlabel(app.UIAxes2, 'Pixels')
            ylabel(app.UIAxes2, 'Pixels')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [0 500 1000 1500 2000];
            app.UIAxes2.XTickLabel = {'0'; '500'; '1000'; '1500'; '2000'};
            app.UIAxes2.YTick = [0 500 1000 1500 2000];
            app.UIAxes2.YTickLabel = {'0'; '500'; '1000'; '1500'; '2000'};
            app.EquipmentsetupPanel = uipanel(app.UIFigure);
            app.EquipmentsetupPanel.Title = 'Equipment set-up';
            app.PixelshorizontalEditFieldLabel = uilabel(app.EquipmentsetupPanel);
            app.PixelshorizontalEditFieldLabel.Text = 'Pixels [horizontal]';
            app.PixelsHorizontalEditField = uieditfield(app.EquipmentsetupPanel, 'numeric');
            app.PixelsHorizontalEditField.Editable = 'off';
            app.PixelsHorizontalEditField.Value = 2464;
            app.PixelSizemEditFieldLabel = uilabel(app.EquipmentsetupPanel);
            app.PixelSizemEditFieldLabel.Text = 'Pixel Size [m]';
            app.PixelSizeEditField = uieditfield(app.EquipmentsetupPanel, 'numeric');
            app.PixelSizeEditField.Editable = 'off';
            app.PixelSizeEditField.Value = 3.45e-06;
            app.CameraDropDownLabel = uilabel(app.EquipmentsetupPanel);
            app.CameraDropDownLabel.Text = 'Camera';
            app.CameraDropDown = uidropdown(app.EquipmentsetupPanel);
            app.CameraDropDown.Items = {'MAKO G-507C'};
            app.CameraDropDown.Value = 'MAKO G-507C';
            app.SensorDropDownLabel = uilabel(app.EquipmentsetupPanel);
            app.SensorDropDownLabel.Text = 'Sensor';
            app.SensorDropDown = uidropdown(app.EquipmentsetupPanel);
            app.SensorDropDown.Items = {'SONY IMX264'};
            app.SensorDropDown.Value = 'SONY IMX264';
            app.ScintillatorDropDownLabel = uilabel(app.EquipmentsetupPanel);
            app.ScintillatorDropDownLabel.Text = 'Scintillator';
            app.ScintillatorDropDown = uidropdown(app.EquipmentsetupPanel);
            app.ScintillatorDropDown.Items = {'YAG:Ce'};
            app.ScintillatorDropDown.Value = 'YAG:Ce';
            app.PixelsverticalEditFieldLabel = uilabel(app.EquipmentsetupPanel);
            app.PixelsverticalEditFieldLabel.Text = 'Pixels [vertical]';
            app.PixelsVerticalEditField = uieditfield(app.EquipmentsetupPanel, 'numeric');
            app.PixelsVerticalEditField.Editable = 'off';
            app.PixelsVerticalEditField.Value = 2056;
            app.WellDepthEditFieldLabel = uilabel(app.EquipmentsetupPanel);
            app.WellDepthEditFieldLabel.Text = 'Well Depth';
            app.WellDepthEditField = uieditfield(app.EquipmentsetupPanel, 'numeric');
            app.WellDepthEditField.Editable = 'off';
            app.WellDepthEditField.Value = 10600;
            app.NumberofBitsEditFieldLabel = uilabel(app.EquipmentsetupPanel);
            app.NumberofBitsEditFieldLabel.Text = 'Number of Bits';
            app.NumberofBitsEditField = uieditfield(app.EquipmentsetupPanel, 'numeric');
            app.NumberofBitsEditField.Editable = 'off';
            app.NumberofBitsEditField.Value = 12;
            app.LensfNumberEditFieldLabel = uilabel(app.EquipmentsetupPanel);
            app.LensfNumberEditFieldLabel.Text = 'Lens f Number ';
            app.LensfNumberEditField = uieditfield(app.EquipmentsetupPanel, 'numeric');
            app.LensfNumberEditField.Editable = 'off';
            app.LensfNumberEditField.Value = 1.4;
            app.QuantumEfficiencyEditField_2Label = uilabel(app.EquipmentsetupPanel);
            app.QuantumEfficiencyEditField_2Label.Text = 'Quantum Efficiency';
            app.QuantumEfficiencyEditField = uieditfield(app.EquipmentsetupPanel, 'numeric');
            app.QuantumEfficiencyEditField.Editable = 'off';
            app.QuantumEfficiencyEditField.Value = 0.64;
            app.LensFocalDistancemEditFieldLabel = uilabel(app.EquipmentsetupPanel);
            app.LensFocalDistancemEditFieldLabel.Text = 'Lens Focal Distance [m]';
            app.LensFocalDistanceEditField = uieditfield(app.EquipmentsetupPanel, 'numeric');
            app.LensFocalDistanceEditField.Editable = 'off';
            app.LensFocalDistanceEditField.Value = 0.05;
            app.DiametermEditFieldLabel = uilabel(app.EquipmentsetupPanel);
            app.DiametermEditFieldLabel.Text = 'Diameter [m]';
            app.YAGDiameterEditField = uieditfield(app.EquipmentsetupPanel, 'numeric');
            app.YAGDiameterEditField.Value = 0.02;
            app.FluorescenceEmissionphotonseVEditFieldLabel = uilabel(app.EquipmentsetupPanel);
            app.FluorescenceEmissionphotonseVEditFieldLabel.Text = 'Fluorescence Emission [photons/eV]';
            app.FluorescenceEmissionEditField = uieditfield(app.EquipmentsetupPanel, 'numeric');
            app.FluorescenceEmissionEditField.Value = 30;
            app.TransmissionEditFieldLabel = uilabel(app.EquipmentsetupPanel);
            app.TransmissionEditFieldLabel.Text = 'Transmission';
            app.YAGTransmissionEditField = uieditfield(app.EquipmentsetupPanel, 'numeric');
            app.YAGTransmissionEditField.Editable = 'off';
            app.YAGTransmissionEditField.Value = 0.341;
            app.XraybeamparamtersPanel = uipanel(app.UIFigure);
            app.XraybeamparamtersPanel.Title = 'X-ray beam paramters';
            app.XRayFWOpeningAnglemradLabel = uilabel(app.XraybeamparamtersPanel);
            app.XRayFWOpeningAnglemradLabel.Text = 'X-Ray FW Opening Angle [rad]';
            app.XRayFWOpeningAngleEditField = uieditfield(app.XraybeamparamtersPanel, 'numeric');
            app.XRayFWOpeningAngleEditField.Value = 0.01;
            app.FluxperPulsephotonsEditFieldLabel = uilabel(app.XraybeamparamtersPanel);
            app.FluxperPulsephotonsEditFieldLabel.Text = 'Flux per Pulse [photons]';
            app.FluxperPulseEditField = uieditfield(app.XraybeamparamtersPanel, 'numeric');
            app.FluxperPulseEditField.Value = 1000000;
            app.NumberofPulsesEditFieldLabel = uilabel(app.XraybeamparamtersPanel);
            app.NumberofPulsesEditFieldLabel.Text = 'Number of Pulses';
            app.NumberofPulsesEditField = uieditfield(app.XraybeamparamtersPanel, 'numeric');
            app.NumberofPulsesEditField.Value = 1;
            app.XrayEnergyeVDropDownLabel = uilabel(app.XraybeamparamtersPanel);
            app.XrayEnergyeVDropDownLabel.Text = 'X-ray Energy [eV]';
            app.XrayEnergyeVDropDown = uidropdown(app.XraybeamparamtersPanel);
            app.XrayEnergyeVDropDown.Items = {'9300', '12000', '15000', '18000', '20000', '23000'};
            app.XrayEnergyeVDropDown.Editable = 'on';
            app.XrayEnergyeVDropDown.Value = '9300';
            app.ExperimentalSetupPanel = uipanel(app.UIFigure);
            app.ExperimentalSetupPanel.Title = 'Experimental Set-up';
            app.DistanceIPtowindowmLabel = uilabel(app.ExperimentalSetupPanel);
            app.DistanceIPtowindowmLabel.Text = 'Distance IP to Window [m]';
            app.DistanceIPtoWindowEditField = uieditfield(app.ExperimentalSetupPanel, 'numeric');
            app.DistanceIPtoWindowEditField.Value = 4.8;
            app.DistanceWindowtoSamplemEditFieldLabel = uilabel(app.ExperimentalSetupPanel);
            app.DistanceWindowtoSamplemEditFieldLabel.Text = 'Distance Window to Sample [m]';
            app.DistanceWindowtoSampleEditField = uieditfield(app.ExperimentalSetupPanel, 'numeric');
            app.DistanceWindowtoSampleEditField.Value = 0.17;
            app.DistanceSampletoYAGmEditFieldLabel = uilabel(app.ExperimentalSetupPanel);
            app.DistanceSampletoYAGmEditFieldLabel.Text = 'Distance Sample to YAG [m]';
            app.DistanceSampletoYAGEditField = uieditfield(app.ExperimentalSetupPanel, 'numeric');
            app.DistanceSampletoYAGEditField.Value = 0.25;
            app.DistanceYAGtoLensmLabel = uilabel(app.ExperimentalSetupPanel);
            app.DistanceYAGtoLensmLabel.Text = 'Distance YAG to Lens [m]';
            app.DistanceYAGtoLensEditField = uieditfield(app.ExperimentalSetupPanel, 'numeric');
            app.DistanceYAGtoLensEditField.Value = 0.164;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = XPCI_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end